classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DataSetDropDownLabel    matlab.ui.control.Label
        DataSetDropDown         matlab.ui.control.DropDown
        PointNumEditFieldLabel  matlab.ui.control.Label
        PointNumEditField       matlab.ui.control.NumericEditField
        MAXLabel                matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        SpeedSliderLabel        matlab.ui.control.Label
        SpeedSlider             matlab.ui.control.Slider
        Switch                  matlab.ui.control.Switch
        ResetButton             matlab.ui.control.Button
        labelEditFieldLabel     matlab.ui.control.Label
        labelEditField          matlab.ui.control.EditField
        Lamp                    matlab.ui.control.Lamp
        FrameSliderLabel        matlab.ui.control.Label
        FrameSlider             matlab.ui.control.Slider
        labelEditField_2        matlab.ui.control.EditField
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        appTimer % Timer object
        myData
        myLab
        useDATA
    end
    
    methods (Access = private)
        
        function appTimerFcn(app,~,~)
            
            app.labelEditField.Value=app.useDATA{1,3}{app.PointNumEditField.Value,1};
            app.labelEditField_2.Value=app.useDATA{1,4}{app.PointNumEditField.Value,1};
            x1=app.useDATA{1,2}(app.PointNumEditField.Value,1);
            y1=app.useDATA{1,2}(app.PointNumEditField.Value,2);
            z1=app.useDATA{1,2}(app.PointNumEditField.Value,3);
            x2=app.useDATA{1,2}(app.PointNumEditField.Value,4);
            y2=app.useDATA{1,2}(app.PointNumEditField.Value,5);
            z2=app.useDATA{1,2}(app.PointNumEditField.Value,6);
            plot3(app.UIAxes,x1,y1,z1,'o');
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,x2,y2,z2,'o');
            hold(app.UIAxes,"off");
            plot(app.UIAxes_2,x1,y1,'o');
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2,x2,y2,'o');
            hold(app.UIAxes_2,"off");
            plot(app.UIAxes_3,x1,z1,'o');
            hold(app.UIAxes_3,"on");
            plot(app.UIAxes_3,x2,z2,'o');
            hold(app.UIAxes_3,"off");
            plot(app.UIAxes_4,y1,z1,'o');
            hold(app.UIAxes_4,"on");
            plot(app.UIAxes_4,y2,z2,'o');
            hold(app.UIAxes_4,"off");
            switch(app.DataSetDropDown.Value)
                case 1
                    framenum=num2str(app.PointNumEditField.Value-1,'%06d');
                case 2
                    framenum=num2str(app.PointNumEditField.Value-1+12*25,'%06d');
                case 3
                    framenum=num2str(app.PointNumEditField.Value-1,'%06d');
                case 4
                    framenum=num2str(app.PointNumEditField.Value-1+6*25,'%06d');
                case 5
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 6
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 7
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 8
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 9
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
            end
            
            imagepath = ['video\' num2str(app.DataSetDropDown.Value) '\' framenum '.png'];
            app.Image.ImageSource = imagepath;
            app.FrameSlider.Value=app.PointNumEditField.Value/10;
            app.PointNumEditField.Value=app.PointNumEditField.Value+1;
        end
        
        function getDataSet(app)%initial DataSet
            app.useDATA{1,1}=app.myLab.label_all{1,app.DataSetDropDown.Value};
            if app.DataSetDropDown.Value<=5
                app.useDATA{1,2}=app.myData.proDepth{app.DataSetDropDown.Value,1};
            else
                app.useDATA{1,2}=app.myData.noviceDepth{app.DataSetDropDown.Value-5,1};
            end
            [num,~]=size(app.useDATA{1,2});
            Class={'A','B','C','D1','D2'};
            app.useDATA{1,3}(1:num,1)={'Non'};
            app.useDATA{1,4}(1:num,1)={'?'};
            [~,length]=size(app.useDATA{1,1});
            length=(length-1)/2;
            for j=1:5
                for i=1:length
                    if ~isnan(app.useDATA{1,1}{j,i*2})
                        app.useDATA{1,3}(app.useDATA{1,1}{j,i*2}:app.useDATA{1,1}{j,i*2+1},1)=Class(1,j);
                    end
                end
            end
            if exist("net.mat","file")
                load('net.mat');
                app.useDATA{1,4} = cellstr(classify(net,app.useDATA{1,2}')');
            end
            app.Label.Text=num2str(num);
            app.PointNumEditField.Limits=[0,num];
            app.UIAxes.XLim=[min(min(app.useDATA{1,2}(:,1)),min(app.useDATA{1,2}(:,4))) max(max(app.useDATA{1,2}(:,1)),max(app.useDATA{1,2}(:,4)))];
            app.UIAxes_2.XLim=[min(min(app.useDATA{1,2}(:,1)),min(app.useDATA{1,2}(:,4))) max(max(app.useDATA{1,2}(:,1)),max(app.useDATA{1,2}(:,4)))];
            app.UIAxes_3.XLim=[min(min(app.useDATA{1,2}(:,1)),min(app.useDATA{1,2}(:,4))) max(max(app.useDATA{1,2}(:,1)),max(app.useDATA{1,2}(:,4)))];
            app.UIAxes.YLim=[min(min(app.useDATA{1,2}(:,2)),min(app.useDATA{1,2}(:,5))) max(max(app.useDATA{1,2}(:,2)),max(app.useDATA{1,2}(:,5)))];
            app.UIAxes_2.YLim=[min(min(app.useDATA{1,2}(:,2)),min(app.useDATA{1,2}(:,5))) max(max(app.useDATA{1,2}(:,2)),max(app.useDATA{1,2}(:,5)))];
            app.UIAxes_4.XLim=[min(min(app.useDATA{1,2}(:,2)),min(app.useDATA{1,2}(:,5))) max(max(app.useDATA{1,2}(:,2)),max(app.useDATA{1,2}(:,5)))];
            app.UIAxes.ZLim=[min(min(app.useDATA{1,2}(:,3)),min(app.useDATA{1,2}(:,6))) max(max(app.useDATA{1,2}(:,3)),max(app.useDATA{1,2}(:,6)))];
            app.UIAxes_3.YLim=[min(min(app.useDATA{1,2}(:,3)),min(app.useDATA{1,2}(:,6))) max(max(app.useDATA{1,2}(:,3)),max(app.useDATA{1,2}(:,6)))];
            app.UIAxes_4.YLim=[min(min(app.useDATA{1,2}(:,3)),min(app.useDATA{1,2}(:,6))) max(max(app.useDATA{1,2}(:,3)),max(app.useDATA{1,2}(:,6)))];
            app.FrameSlider.Limits=[0,num/10];
            Reset_all(app);
            
            
        end
        
        function Reset_all(app)
            app.SpeedSlider.Value=1;
            app.Switch.Value="Pause";
            app.PointNumEditField.Value=1;
            if strcmp(app.appTimer.Running, 'on')
                    stop(app.appTimer);
            end
            app.appTimer.Period=0.04/app.SpeedSlider.Value;
            app.Lamp.Color=[1.00,0.00,0.00];
            app.labelEditField.Value=app.useDATA{1,3}{1,1};
            app.labelEditField_2.Value=app.useDATA{1,4}{1,1};
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            app.FrameSlider.Value=1;
             switch(app.DataSetDropDown.Value)
                case 1
                    framenum=num2str(app.PointNumEditField.Value-1,'%06d');
                case 2
                    framenum=num2str(app.PointNumEditField.Value-1+12*25,'%06d');
                case 3
                    framenum=num2str(app.PointNumEditField.Value-1,'%06d');
                case 4
                    framenum=num2str(app.PointNumEditField.Value-1+6*25,'%06d');
                case 5
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 6
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 7
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 8
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
                case 9
                    framenum=num2str(app.PointNumEditField.Value-1+25,'%06d');
            end
            
            imagepath = ['video\' num2str(app.DataSetDropDown.Value) '\' framenum '.png'];
            app.Image.ImageSource = imagepath;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myData=load('data.mat');
            app.myLab=load('label_all.mat');                            %load data
            app.DataSetDropDown.Items=app.myLab.label{:,:};
            app.DataSetDropDown.ItemsData=1:numel(app.myLab.label{:,:});%initial selector
            
            app.appTimer=timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', 0.04/app.SpeedSlider.Value, ...                       % Period is 1 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.appTimerFcn);   % Callback that runs every period
            
            app.Image.ImageSource = 'video\Pro2-1\000000.png';
            getDataSet(app);
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            if value~=0
                if strcmp(app.appTimer.Running, 'on')
                    stop(app.appTimer);
                    app.appTimer.Period=0.04/app.SpeedSlider.Value;
                    start(app.appTimer);
                else
                    app.appTimer.Period=0.04/app.SpeedSlider.Value;
                end   
            else
                if strcmp(app.appTimer.Running, 'on')
                    stop(app.appTimer);
                    app.Lamp.Color=[1.00,0.00,0.00];
                    app.Switch.Value='Pause';
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value=="Run"
                if strcmp(app.appTimer.Running, 'off')
                    start(app.appTimer);
                end
                app.Lamp.Color=[0.00,1.00,0.00];
            else
                stop(app.appTimer);
                app.Lamp.Color=[1.00,0.00,0.00];
            end
        end

        % Value changed function: DataSetDropDown
        function DataSetDropDownValueChanged(app, event)
            Reset_all(app);
            getDataSet(app);
            %app.PointNumEditField.Value=value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            Reset_all(app);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            if app.FrameSlider.Value==0
                app.FrameSlider.Value=0.1;
            end
            app.PointNumEditField.Value=fix(app.FrameSlider.Value*10);
            app.labelEditField.Value=app.useDATA{1,3}{app.PointNumEditField.Value,1};
            app.labelEditField_2.Value=app.useDATA{1,4}{app.PointNumEditField.Value,1};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataSetDropDownLabel = uilabel(app.UIFigure);
            app.DataSetDropDownLabel.Text = 'DataSet';
            app.DataSetDropDown = uidropdown(app.UIFigure);
            app.DataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSetDropDownValueChanged, true);
            app.PointNumEditFieldLabel = uilabel(app.UIFigure);
            app.PointNumEditFieldLabel.Text = 'PointNum';
            app.PointNumEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointNumEditField.Limits = [1 Inf];
            app.PointNumEditField.ValueDisplayFormat = '%11.7g';
            app.PointNumEditField.Value = 1;
            app.MAXLabel = uilabel(app.UIFigure);
            app.MAXLabel.Text = 'MAX:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2D-xy')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '2D-xz')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '2D-yz')
            xlabel(app.UIAxes_4, 'Y')
            ylabel(app.UIAxes_4, 'Z')
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0 1];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Pause', 'Run'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Pause';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.labelEditFieldLabel = uilabel(app.UIFigure);
            app.labelEditFieldLabel.Text = 'label';
            app.labelEditField = uieditfield(app.UIFigure, 'text');
            app.labelEditField.Editable = 'off';
            app.labelEditField.Value = 'Non';
            app.Lamp = uilamp(app.UIFigure);
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.labelEditField_2 = uieditfield(app.UIFigure, 'text');
            app.labelEditField_2.Editable = 'off';
            app.labelEditField_2.Value = '?';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end